classdef viel_dioden_scatter5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        TextArea     matlab.ui.control.TextArea
        stopButton   matlab.ui.control.Button
        startButton  matlab.ui.control.Button
        UIAxes2      matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        interface     % TCP server
        TimerObj      % timer
        data          % save ux,uy,us
        %rawList = {}  % Cell list of raw data rows
        dataLogFile = "esp32_test_data.txt";
        memoryLogFile = "esp32_memory_data.txt";
    end

    methods (Access = private)

        % Initialization function
        function startupFcn(app)
            app.data = table([], [], [], 'VariableNames', {'uxVoltage','uyVoltage','usVoltage'});
            writelines("", app.dataLogFile);
            writelines("", app.memoryLogFile);
        end

        function startAcquisition(app)
            host = "10.203.196.138";
            port = 5025;
            try
                app.interface = tcpclient(host, port, "Timeout", 10);
                configureTerminator(app.interface, "LF");
                flush(app.interface);
                disp("Connected TCP");

                % Continuous data collection mode activated
                writeline(app.interface, "INITiate:CONTinuous:ALL ON");


                % Creating and starting a timer
                app.TimerObj = timer( ...
                    'ExecutionMode', 'fixedSpacing', ...
                    'Period', 0.3, ...
                    'TimerFcn', @(~,~)app.readAndUpdate());

                start(app.TimerObj);

            catch ME
                uialert(app.UIFigure, "connection failed：" + ME.message, "Wrong");
                disp(getReport(ME)); % print the error message
            end
        end

        % Get real time data
        function readAndUpdate(app)
            if isempty(app.interface) || ~isvalid(app.interface)
                disp("stop reading");
                return;
            end
            try
                % Ask for the number of readable data
                count = writeread(app.interface, "DATA:POINts?");
                count = str2num(count);
                disp("now can we read the numble of the points：");
                disp(count);

                if count > 0

                    writeline(app.interface, "DATA:REMove? " + num2str(count));
                    pause(0.3);


                    while count > 0
                        if app.interface.NumBytesAvailable > 0
                            raw = read(app.interface, app.interface.NumBytesAvailable, "char");
                            line1 = string(raw);
                            disp("receive the data:");
                            disp(line1);

                            if strlength(line1) > 0
                                writelines(string(line1), app.dataLogFile, WriteMode="append", LineEnding=" ");
                          
                                % splitCells = strsplit(strtrim(line1), ',');
                                % parts =str2double(splitCells);

                                splitCells = strsplit(strtrim(line1), ',');
                                if numel(splitCells) >= 12
                                  
                                    ux = str2double(splitCells{10});
                                    uy = str2double(splitCells{11});
                                    us = str2double(splitCells{12});
                                     
                                    % if all(cellfun(@(x) ischar(x) || isstring(x), splitCells)) && numel(splitCells) >= 12
                                    %     parts = str2double(splitCells);
                                    % if numel(parts) >= 12
                                    if all(~isnan([ux, uy, us]))
                                        % ux = parts(10);
                                        % uy = parts(11);
                                        % us = parts(12);
                                    app.data(end+1, :) = {ux, uy, us};
                                    disp(app.data);
                                    disp(["receive the data：", ux, uy, us]);
                                else
                                    disp("not enough data, skip");
                                end
                            end

                            count = count - 1;
                        else
                            disp("not data, skip");
                            break;
                        end
                    end

                        line2 = writeread(app.interface, "SYSTem:DEBUg:MEMory?");
                        writelines(line2, app.memoryLogFile, WriteMode="append", LineEnding=" ");

                        % Limit data points to a maximum of 200
                        if height(app.data) > 200
                            app.data = tail(app.data, 200);
                        end

                        app.updateStaticPlot();                   
                    end
                end
                catch ME
                    disp("Data read errors：" + ME.message);
                end
            end


            % update the picture
            function updateStaticPlot(app)
                if height(app.data) < 1
                    return;
                end

                uxVoltage = app.data.uxVoltage;
                uyVoltage = app.data.uyVoltage;
                Intensity = app.data.usVoltage;


                % Set colour mapping range
                c_min = min(Intensity);
                c_max = max(Intensity);

                % Ensure symmetry of X & Y axes (centre alignment)
                x_min = min(uxVoltage);
                x_max = max(uxVoltage);
                y_min = min(uyVoltage);
                y_max = max(uyVoltage);

                axis_limit = max(abs([x_min, x_max, y_min, y_max]));
                x_min = -axis_limit;
                x_max = axis_limit;
                y_min = -axis_limit;
                y_max = axis_limit;


                x_margin = 0.02; % extra margin
                y_margin = 0.02;

                xlim(app.UIAxes, [x_min - x_margin, x_max + x_margin]);
                ylim(app.UIAxes, [y_min - y_margin, y_max + y_margin]);

                % Set X/Y axis scale (0.05 interval)
                x_step = 0.05;
                y_step = 0.05;
                xticks(app.UIAxes, x_min:x_step:x_max);
                yticks(app.UIAxes, y_min:y_step:y_max);

                % Clear the image to prevent overlapping
                cla(app.UIAxes);
                hold(app.UIAxes, "on");

                % Draw X-axis & Y-axis centre lines
                plot(app.UIAxes, [x_min, x_max], [0, 0], 'k-', 'LineWidth', 1.5); % X axis
                plot(app.UIAxes, [0, 0], [y_min, y_max], 'k-', 'LineWidth', 1.5); % Y axis

                % Plot scatter with colours representing light intensity
                scatter(app.UIAxes, uxVoltage, uyVoltage, 5 + 40 * (Intensity - c_min) / (c_max - c_min), Intensity, 'filled');% The point size range is set between 5 and 45.

                % add the colour bar representing light intensity
                colormap(app.UIAxes, jet); % use jet colour mapping and make the colour from blue to red
                colorbar(app.UIAxes); % Show colour bar
                clim(app.UIAxes, [c_min, c_max]); % set the colour range

                xlabel(app.UIAxes, 'uxVoltage (V)');
                ylabel(app.UIAxes, 'uyVoltage (V)');
                title(app.UIAxes, 'XY Scatter Plot with Intensity');

                hold(app.UIAxes, "off");


                % Heatmap
                X=uxVoltage;
                Y=uyVoltage;

                % Generate 50 equally spaced points in X/Y direction
                x_range = linspace(min(X), max(X), 50);
                y_range = linspace(min(Y), max(Y), 50);
                [X_grid, Y_grid] = meshgrid(x_range, y_range);% Generate Grid Point Matrix

                % Calculate intensity values on the grid by interpolation
                Intensity_grid = griddata(X, Y, Intensity, X_grid, Y_grid, 'cubic');

                % draw the Heatmap
                imagesc(app.UIAxes2, x_range, y_range, Intensity_grid);
                set(app.UIAxes2, 'YDir', 'normal');  % Invert Y-axis
                colormap(app.UIAxes2,turbo);  % Set the colour mapping
                colorbar(app.UIAxes2);  % Add a colour bar

                xlabel(app.UIAxes2, 'X position');
                ylabel(app.UIAxes2, 'Y position');
                title(app.UIAxes2, 'Laser Spot Intensity Heatmap');
            end

            % Stop Collection
            function stopAcquisition(app)
                try
                    if ~isempty(app.TimerObj) && isvalid(app.TimerObj)
                        stop(app.TimerObj);
                        delete(app.TimerObj);
                    end

                    if ~isempty(app.interface)
                        writeline(app.interface, "INITiate:CONTinuous OFF");
                        clear app.interface;
                    end

                    disp("Stop Collection");
                catch ME
                    disp("Stop failed: " + ME.message);
                end
            end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
        app.stopAcquisition();
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
        app.startAcquisition();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'laser spot position with intensity')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Heatmap')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'4 quadrat dioden scatterplot'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viel_dioden_scatter5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end